classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ParametriPanel              matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        LunghezzamEditFieldLabel    matlab.ui.control.Label
        LunghezzamEditField         matlab.ui.control.NumericEditField
        Densitkgm3EditFieldLabel    matlab.ui.control.Label
        Densitkgm3EditField         matlab.ui.control.NumericEditField
        MassakgEditFieldLabel       matlab.ui.control.Label
        MassakgEditField            matlab.ui.control.NumericEditField
        VelocitinizialemsEditFieldLabel  matlab.ui.control.Label
        VelocitinizialemsEditField  matlab.ui.control.NumericEditField
        AmpiezzaForzaimpulsivaNEditFieldLabel  matlab.ui.control.Label
        AmpiezzaForzaimpulsivaNEditField  matlab.ui.control.NumericEditField
        RaggiomEditFieldLabel       matlab.ui.control.Label
        RaggiomEditField            matlab.ui.control.NumericEditField
        DuratasEditFieldLabel       matlab.ui.control.Label
        DuratasEditField            matlab.ui.control.NumericEditField
        GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        RotazionesistemadiriferimentosolidalePanel  matlab.ui.container.Panel
        PlayButton                  matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        Slider                      matlab.ui.control.Slider
        GraficiVelocitAngolariSistemadiriferimentofissoPanel  matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        R % Matrice di rotazione
        t % Vettore dei tempi
        tf %durata simulazione
        frame %frame
        f1 %flag
        Frame_Duration %Durata del frame
        Total_Frames %Frames totali
        index %Indici
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: 
        % GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel, 
        % ParametriPanel, 
        % RotazionesistemadiriferimentosolidalePanel, StartButton
        function StartButtonPushed(app, event)
           r = app.RaggiomEditField.Value;
           l = app.LunghezzamEditField.Value;
           Vin = app.VelocitinizialemsEditField.Value;
           Fy = app.AmpiezzaForzaimpulsivaNEditField.Value;
           app.tf = app.DuratasEditField.Value;
           if strcmp(app.Switch.Value,'Massa')
               m = app.MassakgEditField.Value;
           else
               dens = app.Densitkgm3EditField.Value;
               m = pi*r^2*l*dens;
           end
           I = m*[r^2/4+l^2/12 0 0;0 r^2/4+l^2/12 0; 0 0 r^2/2]; %Tensore d'inerzia
           W0 = [0; 0; Vin/r] + (I^-1)*[Fy*l; 0; 0]; %Velocità angolare in 0+
           CI = [W0;1;0;0;0;1;0;0;0;1];
           [app.t,Results] = ode45(@Rotation3D, [0 app.tf], CI);
           app.Slider.Limits=[0,size(app.t,1)];
           app.R = zeros(length(app.t),3,3);
            for i = 1:length(app.t)
                app.R(i,:,:) = [Results(i,4), Results(i,5), Results(i,6); 
                Results(i,7), Results(i,8), Results(i,9);
                Results(i,10), Results(i,11), Results(i,12)];
            end
            Ws = zeros(3,length(app.t));
            for i = 1:length(app.t)
                Ws(:,i) = [Results(i,1), Results(i,2), Results(i,3)];
            end
           plot(app.UIAxes,app.t,Results(:,1));
           plot(app.UIAxes2,app.t,Results(:,2));
           plot(app.UIAxes3,app.t,Results(:,3));
           
           for i=1:length(app.t)
                Wsig(:,i)=squeeze(app.R(i,:,:))*Ws(:,i); 
           end
           plot(app.UIAxes_2,app.t,Wsig(1,:));
           plot(app.UIAxes2_2,app.t,Wsig(2,:));
           plot(app.UIAxes3_2,app.t,Wsig(3,:));
           
           app.PlayButton.Enable = 'on';
           app.Slider.Enable = 'on';
           function [Xdot] = Rotation3D(~,x)

                Xdot = [ (I(2,2)*x(2)*x(3) - I(3,3)*x(2)*x(3))/I(1,1);
                        -(I(1,1)*x(1)*x(3) - I(3,3)*x(1)*x(3))/I(2,2);
                         (I(1,1)*x(1)*x(2) - I(2,2)*x(1)*x(2))/I(3,3);
                         x(3)*x(5) - x(2)*x(6);
                         x(1)*x(6) - x(3)*x(4);
                         x(2)*x(4) - x(1)*x(5);
                         x(3)*x(8) - x(2)*x(9);
                         x(1)*x(9) - x(3)*x(7);
                         x(2)*x(7) - x(1)*x(8);
                         x(3)*x(11) - x(2)*x(12);
                         x(1)*x(12) - x(3)*x(10);
                         x(2)*x(10) - x(1)*x(11) ];  
           end
       FPS = 60; % Frame per Second desiderati
app.Frame_Duration = 1/FPS;
app.Total_Frames = app.tf*FPS;
app.Slider.Limits=[0,app.Total_Frames];
app.index = zeros(app.Total_Frames,1); % Vettore che conterrà gli indici degli elementi del vettore t equispaziati
for i = 1:app.Total_Frames
    val = app.Frame_Duration*i; % Calcolo l'i-esimo instante di tempo per ottenere gli FPS desiderati  
    [~,app.index(i)] = min( abs( app.t-val ) ); % Estraggo dal vettore t l'indice dell'elemento che meglio approssima l'istante di tempo desiderato
end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.f1=0;  
            hold(app.UIAxes4,'off')
            a1=quiver3(app.UIAxes4,0,0,0,app.R(1,1,1),app.R(1,1,2),app.R(1,1,3),'r');
              hold(app.UIAxes4,'on')
              a2=quiver3(app.UIAxes4,0,0,0,app.R(1,2,1),app.R(1,2,2),app.R(1,2,3),'b');
              a3=quiver3(app.UIAxes4,0,0,0,app.R(1,3,1),app.R(1,3,2),app.R(1,3,3),'g');
              legend(app.UIAxes4,'u_1','u_2','u_3');
              xlabel(app.UIAxes4,'e_1');
              ylabel(app.UIAxes4,'e_2');
              zlabel(app.UIAxes4,'e_3');
              app.Slider.Limits=[0,app.Total_Frames];
                  for ind=1:app.Total_Frames
                      if(app.f1)
                          break
                      end
                      tic
                      app.Slider.Value=ind;
                      a1.UData = app.R(app.index(ind),1,1); % Aggiorno l'asse X in base alle tre componenti della prima colonna di R
                      a1.VData = app.R(app.index(ind),1,2);
                      a1.WData = app.R(app.index(ind),1,3);
                      a2.UData = app.R(app.index(ind),2,1); % Aggiorno l'asse Y in base alle tre componenti della seconda colonna di R
                      a2.VData = app.R(app.index(ind),2,2);
                      a2.WData = app.R(app.index(ind),2,3);
                      a3.UData = app.R(app.index(ind),3,1); % Aggiorno l'asse Z in base alle tre componenti della terza colonna di R
                      a3.VData = app.R(app.index(ind),3,2);
                      a3.WData = app.R(app.index(ind),3,3);
                      pause(app.Frame_Duration - toc);
                  end 
                  
              hold(app.UIAxes4,'off')
              
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            hold(app.UIAxes4,'off');
            app.frame = round(app.Slider.Value);
            if(app.frame==0)
               app.frame=1; 
            end
            app.f1=0;
            a1=quiver3(app.UIAxes4,0,0,0,app.R(app.index(app.frame),1,1),app.R(app.index(app.frame),1,2),app.R(app.index(app.frame),1,3),'r');
            hold(app.UIAxes4,'on');
            a2=quiver3(app.UIAxes4,0,0,0,app.R(app.index(app.frame),2,1),app.R(app.index(app.frame),2,2),app.R(app.index(app.frame),2,3),'b');
            a3=quiver3(app.UIAxes4,0,0,0,app.R(app.index(app.frame),3,1),app.R(app.index(app.frame),3,2),app.R(app.index(app.frame),3,3),'g');
            for ind=app.frame:app.Total_Frames
                      if(app.f1)
                          break
                      end
                      tic
                      app.Slider.Value=ind;
                      app.Slider.Value=ind;
                      a1.UData = app.R(app.index(ind),1,1); % Aggiorno l'asse X in base alle tre componenti della prima colonna di R
                      a1.VData = app.R(app.index(ind),1,2);
                      a1.WData = app.R(app.index(ind),1,3);
                      a2.UData = app.R(app.index(ind),2,1); % Aggiorno l'asse Y in base alle tre componenti della seconda colonna di R
                      a2.VData = app.R(app.index(ind),2,2);
                      a2.WData = app.R(app.index(ind),2,3);
                      a3.UData = app.R(app.index(ind),3,1); % Aggiorno l'asse Z in base alle tre componenti della terza colonna di R
                      a3.VData = app.R(app.index(ind),3,2);
                      a3.WData = app.R(app.index(ind),3,3);
                      pause(app.Frame_Duration - toc);
            end
            app.f1=1;
        end

        % Value changed function: AmpiezzaForzaimpulsivaNEditField, 
        % Densitkgm3EditField, DuratasEditField, 
        % LunghezzamEditField, MassakgEditField, RaggiomEditField, 
        % Switch, VelocitinizialemsEditField
        function SwitchValueChanged(app, event)
          app.Slider.Enable = 'off';
          app.PlayButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ParametriPanel = uipanel(app.UIFigure);
            app.ParametriPanel.Title = 'Parametri';
            app.ParametriPanel.SizeChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton = uibutton(app.ParametriPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Switch = uiswitch(app.ParametriPanel, 'slider');
            app.Switch.Items = {'Massa', 'Densità'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Densità';
            app.LunghezzamEditFieldLabel = uilabel(app.ParametriPanel);
            app.LunghezzamEditFieldLabel.Text = 'Lunghezza [m]';
            app.LunghezzamEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.LunghezzamEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LunghezzamEditField.Value = 1;
            app.Densitkgm3EditFieldLabel = uilabel(app.ParametriPanel);
            app.Densitkgm3EditFieldLabel.Text = 'Densità [kg/m^3]';
            app.Densitkgm3EditField = uieditfield(app.ParametriPanel, 'numeric');
            app.Densitkgm3EditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Densitkgm3EditField.Value = 2700;
            app.MassakgEditFieldLabel = uilabel(app.ParametriPanel);
            app.MassakgEditFieldLabel.Text = 'Massa [kg]';
            app.MassakgEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.MassakgEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.VelocitinizialemsEditFieldLabel = uilabel(app.ParametriPanel);
            app.VelocitinizialemsEditFieldLabel.Text = 'Velocità iniziale [m/s]';
            app.VelocitinizialemsEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.VelocitinizialemsEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.VelocitinizialemsEditField.Value = 2;
            app.AmpiezzaForzaimpulsivaNEditFieldLabel = uilabel(app.ParametriPanel);
            app.AmpiezzaForzaimpulsivaNEditFieldLabel.Text = 'Ampiezza Forza impulsiva [N]';
            app.AmpiezzaForzaimpulsivaNEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.AmpiezzaForzaimpulsivaNEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.AmpiezzaForzaimpulsivaNEditField.Value = 100;
            app.RaggiomEditFieldLabel = uilabel(app.ParametriPanel);
            app.RaggiomEditFieldLabel.Text = 'Raggio [m]';
            app.RaggiomEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.RaggiomEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RaggiomEditField.Value = 0.5;
            app.DuratasEditFieldLabel = uilabel(app.ParametriPanel);
            app.DuratasEditFieldLabel.Text = 'Durata [s]';
            app.DuratasEditField = uieditfield(app.ParametriPanel, 'numeric');
            app.DuratasEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DuratasEditField.Value = 10;
            app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel = uipanel(app.UIFigure);
            app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel.Title = 'Grafici Velocità Angolari Sistema di riferimento solidale (S)';
            app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel.SizeChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.UIAxes = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel);
            title(app.UIAxes, 'ωx')
            app.UIAxes2 = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel);
            title(app.UIAxes2, 'ωy')
            app.UIAxes3 = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentosolidaleSPanel);
            title(app.UIAxes3, 'ωz')
            app.RotazionesistemadiriferimentosolidalePanel = uipanel(app.UIFigure);
            app.RotazionesistemadiriferimentosolidalePanel.Title = 'Rotazione sistema di riferimento solidale';
            app.RotazionesistemadiriferimentosolidalePanel.SizeChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PlayButton = uibutton(app.RotazionesistemadiriferimentosolidalePanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.UIAxes4 = uiaxes(app.RotazionesistemadiriferimentosolidalePanel);
            app.UIAxes4.XLim = [-1.5 1.5];
            app.UIAxes4.YLim = [-1.5 1.5];
            app.UIAxes4.ZLim = [-1.5 1.5];
            app.UIAxes4.XTick = [-1 0 1];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [-1 0 1];
            app.UIAxes4.ZTick = [-1 0 1];
            app.Slider = uislider(app.RotazionesistemadiriferimentosolidalePanel);
            app.Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.GraficiVelocitAngolariSistemadiriferimentofissoPanel = uipanel(app.UIFigure);
            app.GraficiVelocitAngolariSistemadiriferimentofissoPanel.Title = 'Grafici Velocità Angolari Sistema di riferimento fisso (Σ)';
            app.UIAxes_2 = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentofissoPanel);
            title(app.UIAxes_2, 'ωX')
            app.UIAxes2_2 = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentofissoPanel);
            title(app.UIAxes2_2, 'ωY')
            app.UIAxes3_2 = uiaxes(app.GraficiVelocitAngolariSistemadiriferimentofissoPanel);
            title(app.UIAxes3_2, 'ωZ')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end